classdef practical_meg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EditField                      matlab.ui.control.NumericEditField
        MeasuredhearingthresholdleftrightdBFSRMSEditField  matlab.ui.control.NumericEditField
        GemesseneHrschwelledBFSRMSLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        NoteTextArea                   matlab.ui.control.TextArea
        NoteTextAreaLabel              matlab.ui.control.Label
        ChoosestimulustypeButtonGroup  matlab.ui.container.ButtonGroup
        downchirpButton                matlab.ui.control.ToggleButton
        upchirpButton                  matlab.ui.control.ToggleButton
        clickButton                    matlab.ui.control.ToggleButton
        StartsectionButton             matlab.ui.control.Button
        FixationcrossSwitch            matlab.ui.control.Switch
        FixationcrossSwitchLabel       matlab.ui.control.Label
        StopstopsSoundMexProButton     matlab.ui.control.Button
        Experimenttitel                matlab.ui.control.Label
        Probandenname                  matlab.ui.control.EditField
        subjectnameLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
         hfig     = []; % figure for fixation cross
         Settings = importdata(fullfile(fileparts(mfilename('fullpath')),'Code','Settings','Settings.mat')); % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            p = fileparts(mfilename('fullpath'));
            addpath(fullfile(p,'Code','Experimental_scripts')); 
            addpath(fullfile(p,'Code','Fixation_cross')); 
            addpath(fullfile(p,'Code','Picture_GUI')); 
            addpath(fullfile(p,'Code','helper_functions')); % trigger function & saving
            app.MeasuredhearingthresholdleftrightdBFSRMSEditField.Value = app.Settings.Calibration.CalVal(1);
            app.EditField.Value                                         = app.Settings.Calibration.CalVal(2);

        end

        % Value changed function: FixationcrossSwitch
        function FixationcrossSwitchValueChanged(app, event)
            value = app.FixationcrossSwitch.Value;
            switch value
                case 'On'
                    app.hfig = display_fixation_cross;
                    
                case 'Off'
                    close(app.hfig)
            end
        end

        % Button pushed function: StopstopsSoundMexProButton
        function StopstopsSoundMexProButtonPushed(app, event)
            % Wait a LITTLE while for others Scripts to terminate or react
            % appropriate:
            pause(0.05)
            
            % Check if Soundmex is (still) initialized:
            [Success, Initialized] = soundmexpro('initialized');
           
            % first check success of command itself
            if (~Success)
                error('error calling ''initialized''');
            end
            
            % check if device _is_ initialized
            if (Initialized)
                % now exit
                if (1 ~= soundmexpro('exit'))
                    error('error calling ''exit''');
                end
                disp('                SoundmexPRO STOPPED!                   ');
            end
            
            % Show message to the Workspace
            disp('      "Emergency-STOP" of SoundmexPRO used ... !        ');
        end

        % Button pushed function: StartsectionButton
        function StartsectionButtonPushed(app, event)
            if app.clickButton.Value
    	        experimental_script(app.UIFigure,app.Settings,app.Probandenname.Value,'click')
            end
                   
            if app.upchirpButton.Value 
                experimental_script(app.UIFigure,app.Settings,app.Probandenname.Value,'up')
            end

            if app.downchirpButton.Value
                experimental_script(app.UIFigure,app.Settings,app.Probandenname.Value,'down')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.subjectnameLabel = uilabel(app.UIFigure);
            app.subjectnameLabel.Text = 'Subject name';
            app.Probandenname = uieditfield(app.UIFigure, 'text');
            app.Probandenname.Value = 'subjectXX';
            app.Experimenttitel = uilabel(app.UIFigure);
            app.Experimenttitel.Text = 'Practical at MEG';
            app.StopstopsSoundMexProButton = uibutton(app.UIFigure, 'push');
            app.StopstopsSoundMexProButton.ButtonPushedFcn = createCallbackFcn(app, @StopstopsSoundMexProButtonPushed, true);
            app.StopstopsSoundMexProButton.Text = {'Stop '; '(stops SoundMexPro)'};
            app.FixationcrossSwitchLabel = uilabel(app.UIFigure);
            app.FixationcrossSwitchLabel.Text = 'Fixation cross';
            app.FixationcrossSwitch = uiswitch(app.UIFigure, 'slider');
            app.FixationcrossSwitch.ValueChangedFcn = createCallbackFcn(app, @FixationcrossSwitchValueChanged, true);
            app.StartsectionButton = uibutton(app.UIFigure, 'push');
            app.StartsectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartsectionButtonPushed, true);
            app.StartsectionButton.Text = 'Start section ';
            app.ChoosestimulustypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ChoosestimulustypeButtonGroup.Title = 'Choose stimulus type';
            app.clickButton = uitogglebutton(app.ChoosestimulustypeButtonGroup);
            app.clickButton.Text = 'click';
            app.clickButton.Value = true;
            app.upchirpButton = uitogglebutton(app.ChoosestimulustypeButtonGroup);
            app.upchirpButton.Text = 'up-chirp';
            app.downchirpButton = uitogglebutton(app.ChoosestimulustypeButtonGroup);
            app.downchirpButton.Text = 'down-chirp';
            app.NoteTextAreaLabel = uilabel(app.UIFigure);
            app.NoteTextAreaLabel.Text = 'Note';
            app.NoteTextArea = uitextarea(app.UIFigure);
            app.NoteTextArea.Value = {'1.) Write down the subject code along with the personal code word on a coding list for easy anonymization of the data later. '; '2.) Change the calibration values in the settings for each subject before measurement to calibrate in dB SL.'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Code', 'Picture_GUI', 'brain.png');
            app.GemesseneHrschwelledBFSRMSLabel = uilabel(app.UIFigure);
            app.GemesseneHrschwelledBFSRMSLabel.Text = {'Measured hearing threshold'; 'left/right -dB FS (RMS)'};
            app.MeasuredhearingthresholdleftrightdBFSRMSEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = practical_meg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end